classdef MTO_Platform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MTOPlatformUIFigure        matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        Panel_1                    matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        ProblemsAddButton          matlab.ui.control.Button
        AlgorithmsAddButton        matlab.ui.control.Button
        RepsEditField              matlab.ui.control.NumericEditField
        RunTimesEditFieldLabel     matlab.ui.control.Label
        EndNumEditField            matlab.ui.control.NumericEditField
        EndNumEditFieldLabel       matlab.ui.control.Label
        PopSizeEditField           matlab.ui.control.NumericEditField
        PopSizeEditFieldLabel      matlab.ui.control.Label
        AlgorithmsListBox          matlab.ui.control.ListBox
        AlgorithmsListBoxLabel     matlab.ui.control.Label
        ProblemsListBox            matlab.ui.control.ListBox
        ProblemsListBoxLabel       matlab.ui.control.Label
        ButtonGroup                matlab.ui.container.ButtonGroup
        IterationButton            matlab.ui.control.RadioButton
        EvaluationButton           matlab.ui.control.RadioButton
        EndConditionLabel          matlab.ui.control.Label
        Panel_2                    matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        StartButton                matlab.ui.control.Button
        PauseButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        AlgorithmsTree             matlab.ui.container.Tree
        ProblemsTree               matlab.ui.container.Tree
        SelectedAlgoLabel          matlab.ui.control.Label
        SelectedProbLabel          matlab.ui.control.Label
        AlgorithmsDelButton        matlab.ui.control.Button
        ProblemsDelButton          matlab.ui.control.Button
        Panel_3                    matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        FitnessTab                 matlab.ui.container.Tab
        GridLayout8_2              matlab.ui.container.GridLayout
        FitnessUITable             matlab.ui.control.Table
        FitnessStdTab              matlab.ui.container.Tab
        GridLayout8_3              matlab.ui.container.GridLayout
        FitnessStdUITable          matlab.ui.control.Table
        TimeUsedTab                matlab.ui.container.Tab
        GridLayout8                matlab.ui.container.GridLayout
        TimeUITable                matlab.ui.control.Table
        Panel_4                    matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        LogsTextAreaLabel          matlab.ui.control.Label
        LogsTextArea               matlab.ui.control.TextArea
        LogsClearButton            matlab.ui.control.Button
        SaveResultButton           matlab.ui.control.Button
        LoadDataButton             matlab.ui.control.Button
        YLimTypeDropDownLabel      matlab.ui.control.Label
        YLimTypeDropDown           matlab.ui.control.DropDown
        FigureTypeDropDownLabel    matlab.ui.control.Label
        FigureTypeDropDown         matlab.ui.control.DropDown
        TableTypeDropDownLabel     matlab.ui.control.Label
        TableTypeDropDown          matlab.ui.control.DropDown
        SelectedAlgoContextMenu    matlab.ui.container.ContextMenu
        SelectedAlgoSelectAllMenu  matlab.ui.container.Menu
        SelectedProbContextMenu    matlab.ui.container.ContextMenu
        SelectedProbSelectAllMenu  matlab.ui.container.Menu
        AlgorithmsContextMenu      matlab.ui.container.ContextMenu
        AlgorithmsSelectAllMenu    matlab.ui.container.Menu
        ProblemsContextMenu        matlab.ui.container.ContextMenu
        ProblemsSelectAllMenu      matlab.ui.container.Menu
    end

    properties (Access = public)
        reps % number of independent runs
        pop_size % number of population size
        end_num % number of end condition
        result % (problem, algorithm){wallclock, rep * convergence}
        stop_flag % stop button clicked flag
        algo_cell % cell of selected algorithms
        prob_cell % cell of selected problems
        tasks_num_list % list of every problem's tasks num
        data % save data
    end
    
    methods (Access = public)
        
        function printlog(app, str)
            % print log to logs text area
            
            if strcmp(app.LogsTextArea.Value, '')
                app.LogsTextArea.Value = str;
            else
                app.LogsTextArea.Value = [app.LogsTextArea.Value; str];
            end
            drawnow;
            scroll(app.LogsTextArea, 'bottom');
        end
        
        function loadAlgoProb(app)
            % load the algorithms .m file and problems .m file to listbox
            
            app.AlgorithmsListBox.Items(:) = [];
            app.ProblemsListBox.Items(:) = [];
            algo_load = {};
            prob_load = {};
            algo_dir = dir('Algorithms/*m');
            prob_dir = dir('Problems/*.m');
            
            % Algorithms
            for i=1:length(algo_dir)
                algo_load  = [algo_load, algo_dir(i).name(1:end-2)];
            end
            algo_load(strcmp(algo_load, 'Algorithm')) = [];
            app.AlgorithmsListBox.Items = algo_load;
            
            % Problems
            for i=1:length(prob_dir)
                prob_load  = [prob_load, prob_dir(i).name(1:end-2)];
            end
            prob_load(strcmp(prob_load, 'Problem')) = [];
            app.ProblemsListBox.Items = prob_load;
        end
        
        function checkPauseStopStatus(app)
            % This function can be called at any time to check that status of the pause and stop buttons.
            % If paused, it will wait until un-paused.
            % If stopped, it will throw an error to break execution. The error will not be thrown.
            
            if app.stop_flag
                app.changeStartLoadSaveButtonEnable(true);
                error('User Stop')
            end
            
            if strcmp(app.PauseButton.Text, 'Resume')
                app.SaveResultButton.Enable = true;
                waitfor(app.PauseButton,'Text', 'Pause')
            end
        end
        
        function resetTable(app)
            % reset the table data and name
            
            prob_row_cell = {};
            for prob = 1:length(app.prob_cell)
                for task = 1:app.tasks_num_list(prob)
                    prob_row_cell = [prob_row_cell, [app.prob_cell{prob}, num2str(task)]];
                end
            end
            app.FitnessUITable.ColumnName = app.algo_cell;
            app.FitnessStdUITable.ColumnName = app.algo_cell;
            app.TimeUITable.ColumnName = app.algo_cell;
            app.FitnessUITable.RowName = prob_row_cell;
            app.FitnessStdUITable.RowName = prob_row_cell;
            app.TimeUITable.RowName = app.prob_cell;
            app.FitnessUITable.Data = [];
            app.FitnessStdUITable.Data = [];
            app.TimeUITable.Data = [];
        end
        
        function updateTable(app)
            % update fitness, std, time_used table
            
            for algo = 1:length(app.algo_cell)
                row_i = 1;
                for prob = 1:length(app.prob_cell)
                    tasks_num = app.tasks_num_list(prob);
                    for task = 1:tasks_num
                        convergence_task = app.result(prob, algo).convergence(task:tasks_num:end, :);
                        mean_end = mean(convergence_task(:, end));
                        std_end = std(convergence_task(:, end));
                        app.FitnessUITable.Data(row_i, algo) = mean_end;
                        app.FitnessStdUITable.Data(row_i, algo) = std_end;
                        row_i = row_i + 1;
                    end
                    app.TimeUITable.Data(prob, algo) = app.result(prob, algo).clock_time;
                end
            end
            
            % highlight best value
            app.FitnessUITable.removeStyle();
            app.FitnessStdUITable.removeStyle();
            app.TimeUITable.removeStyle();
            highlight = uistyle('BackgroundColor', [0.67,0.95,0.67]);
            worstlight = uistyle('BackgroundColor', [1.00,0.60,0.60]);
            row_i = 1;
            for prob = 1:length(app.prob_cell)
                tasks_num = app.tasks_num_list(prob);
                for task = 1:tasks_num
                    % best
                    [~, index] = min(app.FitnessUITable.Data(row_i, :));
                    app.FitnessUITable.addStyle(highlight, 'cell', [row_i, index]);
                    [~, index] = min(app.FitnessStdUITable.Data(row_i, :));
                    app.FitnessStdUITable.addStyle(highlight, 'cell', [row_i, index]);
                    
                    % worst
                    [~, index] = max(app.FitnessUITable.Data(row_i, :));
                    app.FitnessUITable.addStyle(worstlight, 'cell', [row_i, index]);
                    [~, index] = max(app.FitnessStdUITable.Data(row_i, :));
                    app.FitnessStdUITable.addStyle(worstlight, 'cell', [row_i, index]);
                    
                    row_i = row_i + 1;
                end
                % best
                [~, index] = min(app.TimeUITable.Data(prob, :));
                app.TimeUITable.addStyle(highlight, 'cell', [prob, index]);
                
                % worst
                [~, index] = max(app.TimeUITable.Data(prob, :));
                app.TimeUITable.addStyle(worstlight, 'cell', [prob, index]);
            end
            drawnow;
        end
        
        function updateTableProb(app, prob)
            % update fitness, std, time_used table problem
            
            for algo = 1:length(app.algo_cell)
                tasks_num = app.tasks_num_list(prob);
                row_i = sum(app.tasks_num_list(1:prob-1)) + 1;
                for task = 1:tasks_num
                    convergence_task = app.result(prob, algo).convergence(task:tasks_num:end, :);
                    mean_end = mean(convergence_task(:, end));
                    std_end = std(convergence_task(:, end));
                    app.FitnessUITable.Data(row_i, algo) = mean_end;
                    app.FitnessStdUITable.Data(row_i, algo) = std_end;
                    row_i = row_i + 1;
                end
                app.TimeUITable.Data(prob, algo) = app.result(prob, algo).clock_time;
            end
            
            % highlight best value
            row_i = sum(app.tasks_num_list(1:prob-1)) + 1;
            highlight = uistyle('BackgroundColor', [0.67,0.95,0.67]);
            worstlight = uistyle('BackgroundColor', [1.00,0.60,0.60]);
            tasks_num = app.tasks_num_list(prob);
            for task = 1:tasks_num
                target_index = app.FitnessUITable.StyleConfigurations.TargetIndex;
                style_row = reshape([target_index{:}], [2, length(target_index)]);
                if ~isempty(find(style_row(1, :) == row_i, 1))
                    app.FitnessUITable.removeStyle(find(style_row(1, :) == row_i));
                end
                target_index = app.FitnessStdUITable.StyleConfigurations.TargetIndex;
                style_row = reshape([target_index{:}], [2, length(target_index)]);
                if ~isempty(find(style_row(1, :) == row_i, 1))
                    app.FitnessStdUITable.removeStyle(find(style_row(1, :) == row_i));
                end
                
                % best
                [~, index] = min(app.FitnessUITable.Data(row_i, :));
                app.FitnessUITable.addStyle(highlight, 'cell', [row_i, index]);
                [~, index] = min(app.FitnessStdUITable.Data(row_i, :));
                app.FitnessStdUITable.addStyle(highlight, 'cell', [row_i, index]);
                
                % worst
                [~, index] = max(app.FitnessUITable.Data(row_i, :));
                app.FitnessUITable.addStyle(worstlight, 'cell', [row_i, index]);
                [~, index] = max(app.FitnessStdUITable.Data(row_i, :));
                app.FitnessStdUITable.addStyle(worstlight, 'cell', [row_i, index]);
                row_i = row_i + 1;
            end
            target_index = app.TimeUITable.StyleConfigurations.TargetIndex;
            style_row = reshape([target_index{:}], [2, length(target_index)]);
            if ~isempty(find(style_row(1, :) == prob, 1))
                app.TimeUITable.removeStyle(find(style_row(1, :) == prob));
            end
            % best
            [~, index] = min(app.TimeUITable.Data(prob, :));
            app.TimeUITable.addStyle(highlight, 'cell', [prob, index]);
            
            % worst
            [~, index] = max(app.TimeUITable.Data(prob, :));
            app.TimeUITable.addStyle(worstlight, 'cell', [prob, index]);
            
            drawnow;
        end
        
        function changeStartLoadSaveButtonEnable(app, value)
            app.StartButton.Enable = value;
            app.LoadDataButton.Enable = value;
            app.SaveResultButton.Enable = value;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % App startup function
            
            % add path
            addpath(genpath('./Algorithms/'));
            addpath(genpath('./Problems/'));
            addpath(genpath('./Utils/'));
            
            app.loadAlgoProb();
            app.resetTable();
            
            % read default value
            app.reps = app.RepsEditField.Value;
            app.pop_size = app.PopSizeEditField.Value;
            app.end_num = app.EndNumEditField.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % start this experiment
            
            % off the start button
            app.changeStartLoadSaveButtonEnable(false);
            app.stop_flag = false;
            
            % clear the temporary data
            app.data = [];
            
            % check selected
            algo_num = length(app.AlgorithmsTree.Children);
            prob_num = length(app.ProblemsTree.Children);
            if algo_num == 0
                app.printlog('!!! Please select the Algorithm first !!!');
                app.changeStartLoadSaveButtonEnable(true);
                return;
            end
            if prob_num == 0
                app.printlog('!!! Please select the Problem first !!!');
                app.changeStartLoadSaveButtonEnable(true);
                return;
            end
            
            % initialize the result properties
            for algo = 1:algo_num
                for prob = 1:prob_num
                    app.result(prob, algo).clock_time = 0;
                    app.result(prob, algo).convergence = [];
                end
            end
            
            % read selected algorithms and problems
            app.algo_cell = {};
            for algo = 1:algo_num
                app.algo_cell{algo} = app.AlgorithmsTree.Children(algo).Text;
            end
            app.prob_cell = {};
            for prob = 1:prob_num
                app.prob_cell{prob} = app.ProblemsTree.Children(prob).Text;
                app.tasks_num_list(prob) = app.ProblemsTree.Children(prob).NodeData.getTasksNumber();
            end
            
            app.resetTable();
            
            % main test loop
            app.printlog([newline, '#========= Test Start =========#'])
            tStart = tic;
            for rep = 1:app.reps
                app.printlog(['<========== Rep: ', num2str(rep), ' ==========>']);
                for prob = 1:prob_num
                    app.printlog(['-------- Problem: ', app.ProblemsTree.Children(prob).Text, ' --------']);
                    for algo = 1:algo_num
                        % check pause and stop
                        app.checkPauseStopStatus();
                        
                        % get this experiment's parameters
                        app.printlog([app.AlgorithmsTree.Children(algo).Text, ' is running']);
                        if app.IterationButton.Value
                            iter_num = app.end_num;
                            eva_num = inf;
                        elseif app.EvaluationButton.Value
                            iter_num = inf;
                            eva_num = app.end_num;
                        end
                        pre_run_list = [app.pop_size, iter_num, eva_num];
                        
                        % run
                        data = singleRun(app.AlgorithmsTree.Children(algo).NodeData, app.ProblemsTree.Children(prob).NodeData, pre_run_list);
                        app.result(prob, algo).clock_time = app.result(prob, algo).clock_time + data.clock_time;
                        app.result(prob, algo).convergence = [app.result(prob, algo).convergence; data.convergence];
                    end
                    app.updateTableProb(prob);
                end
                % save data
                app.data.reps = rep;
                app.data.tasks_num_list = app.tasks_num_list;
                app.data.pop_size = app.pop_size;
                app.data.iter_num = iter_num;
                app.data.eva_num = eva_num;
                app.data.algo_cell = app.algo_cell;
                app.data.prob_cell = app.prob_cell';
                app.data.result = app.result;
            end
            
            tEnd = toc(tStart);
            app.printlog(['<------ All Use Time: ', char(duration([0, 0, tEnd])), ' ------>']);
            app.printlog(['#======== Test Finished ========#', newline]);
            
            app.changeStartLoadSaveButtonEnable(true);
        end

        % Button pushed function: AlgorithmsAddButton
        function AlgorithmsAddButtonPushed(app, event)
            % add selected algorithms to selected algorithms tree
            
            algo_selected = app.AlgorithmsListBox.Value;
            for i= 1:length(algo_selected)
                eval(['algo_obj = ', algo_selected{i}, '("',algo_selected{i}, '");']);
                algo_node = uitreenode(app.AlgorithmsTree);
                algo_node.Text = algo_obj.getName();
                algo_node.NodeData = algo_obj;
                algo_node.ContextMenu = app.SelectedAlgoContextMenu;
                
                % child parameter node
                parameter = algo_obj.getParameter();
                for p = 1:2:length(parameter)
                    para_name_node = uitreenode(algo_node);
                    para_name_node.Text = ['[ ', parameter{p}, ' ]'];
                    para_name_node.NodeData = para_name_node.Text;
                    para_name_node.ContextMenu = app.SelectedAlgoContextMenu;
                    para_value_node = uitreenode(algo_node);
                    para_value_node.Text = parameter{p+1};
                    para_value_node.ContextMenu = app.SelectedAlgoContextMenu;
                end
            end
        end

        % Button pushed function: ProblemsAddButton
        function ProblemsAddButtonPushed(app, event)
            % add selected problems to selected problems tree
            
            prob_selected = app.ProblemsListBox.Value;
            for i= 1:length(prob_selected)
                eval(['prob_obj = ', prob_selected{i}, '("',prob_selected{i}, '");']);
                prob_node = uitreenode(app.ProblemsTree);
                prob_node.Text = prob_obj.getName();
                prob_node.NodeData = prob_obj;
                prob_node.ContextMenu = app.SelectedProbContextMenu;
                
                % child parameter node
                parameter = prob_obj.getParameter();
                for p = 1:2:length(parameter)
                    para_name_node = uitreenode(prob_node);
                    para_name_node.Text = ['[ ', parameter{p}, ' ]'];
                    para_name_node.NodeData = para_name_node.Text;
                    para_name_node.ContextMenu = app.SelectedProbContextMenu;
                    para_value_node = uitreenode(prob_node);
                    para_value_node.Text = parameter{p+1};
                    para_value_node.ContextMenu = app.SelectedProbContextMenu;
                end
            end
        end

        % Button pushed function: AlgorithmsDelButton
        function AlgorithmsDelButtonPushed(app, event)
            % delete selected algorithms from algorithms tree
            
            algo_selected = app.AlgorithmsTree.SelectedNodes;
            for i = 1:length(algo_selected)
                if isa(algo_selected(i).Parent, 'matlab.ui.container.Tree')
                    algo_selected(i).delete;
                end
            end
        end

        % Button pushed function: ProblemsDelButton
        function ProblemsDelButtonPushed(app, event)
            % delete selected problems from problems tree
            
            prob_selected = app.ProblemsTree.SelectedNodes;
            for i = 1:length(prob_selected)
                if isa(prob_selected(i).Parent, 'matlab.ui.container.Tree')
                    prob_selected(i).delete;
                end
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            % pause or resume this experiment
            
            if strcmp(app.PauseButton.Text, 'Pause')
                app.StopButton.Enable = 'off';
                app.PauseButton.Text = 'Resume';
                app.printlog('########### Paused! ###########');
            else
                app.StopButton.Enable = 'on';
                app.PauseButton.Text = 'Pause';
                app.printlog('########## Resumed! ##########');
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % stop this experiment
            
            app.changeStartLoadSaveButtonEnable(true);
            app.stop_flag = true;
            app.printlog('#======== Test Stopped ========#');
        end

        % Button pushed function: LogsClearButton
        function LogsClearButtonPushed(app, event)
            % clear logs text area
            
            app.LogsTextArea.Value = '';
        end

        % Value changed function: RepsEditField
        function RepsEditFieldValueChanged(app, event)
            % update reps parameter
            
            app.reps = app.RepsEditField.Value;
        end

        % Value changed function: PopSizeEditField
        function PopSizeEditFieldValueChanged(app, event)
            % update pop_size parameter
            
            app.pop_size = app.PopSizeEditField.Value;
        end

        % Value changed function: EndNumEditField
        function EndNumEditFieldValueChanged(app, event)
            % update end_num parameter
            
            app.end_num = app.EndNumEditField.Value;
        end

        % Node text changed function: AlgorithmsTree
        function AlgorithmsTreeNodeTextChanged(app, event)
            % update algorithm obj parameter
            
            node = event.Node;
            if isa(node.Parent, 'matlab.ui.container.Tree')
                % this is algorithm name node
                node.NodeData.name = node.Text;
            else
                % this is parameter node
                parameter = {};
                % the first node text is parameter name, can't change
                for x = 1:2:length(node.Parent.Children)
                    node.Parent.Children(x).Text = node.Parent.Children(x).NodeData;
                end
                % the second node text is parameter value
                for x = 2:2:length(node.Parent.Children)
                    parameter = [parameter, node.Parent.Children(x).Text];
                end
                node.Parent.NodeData.setParameter(parameter);
            end
        end

        % Node text changed function: ProblemsTree
        function ProblemsTreeNodeTextChanged(app, event)
            % update problem obj parameter
            
            node = event.Node;
            if isa(node.Parent, 'matlab.ui.container.Tree')
                % this is problem node
                node.NodeData.name = node.Text;
            else
                % this is parameter node
                parameter = {};
                % the first node text is parameter name, can't change
                for x = 1:2:length(node.Parent.Children)
                    node.Parent.Children(x).Text = node.Parent.Children(x).NodeData;
                end
                % the second node text is parameter value
                for x = 2:2:length(node.Parent.Children)
                    parameter = [parameter, node.Parent.Children(x).Text];
                end
                node.Parent.NodeData.setParameter(parameter);
            end
        end

        % Context menu opening function: SelectedAlgoContextMenu
        function SelectedAlgoContextMenuOpening(app, event)
            % select all selected algorithms
            
            if ~isempty(app.AlgorithmsTree.Children)
                app.AlgorithmsTree.SelectedNodes = app.AlgorithmsTree.Children;
            end
        end

        % Menu selected function: SelectedProbSelectAllMenu
        function SelectedProbSelectAllMenuSelected(app, event)
            % select all selected problems
            
            if ~isempty(app.ProblemsTree.Children)
                app.ProblemsTree.SelectedNodes = app.ProblemsTree.Children;
            end
        end

        % Context menu opening function: AlgorithmsContextMenu
        function AlgorithmsContextMenuOpening(app, event)
            % select all algorithms
            
            if ~isempty(app.AlgorithmsListBox.Items)
                app.AlgorithmsListBox.Value = app.AlgorithmsListBox.Items;
            end
        end

        % Menu selected function: ProblemsSelectAllMenu
        function ProblemsSelectAllMenuSelected(app, event)
            % select all problems
            
            if ~isempty(app.ProblemsListBox.Items)
                app.ProblemsListBox.Value = app.ProblemsListBox.Items;
            end
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
            % save data, figure, table to folder
            
            % check app.data
            if isempty(app.data)
                app.printlog('!!! Please Run Test At Least 1 Rep !!! ');
                return;
            end
            
            % check selected dir_name
            dir_name = uigetdir('./');
            if dir_name == 0
                app.printlog('!!! User Select Canceled !!! ');
                return;
            end
            
            % save data
            data_save = app.data;
            save([dir_name, '/data_save'], 'data_save');
            
            % save figure
            fig_dir_name = [dir_name, '/data_Figure/'];
            mkdir(fig_dir_name);
            draw_obj = drawFigure;
            for prob = 1:length(data_save.prob_cell)
                tasks_num = data_save.tasks_num_list(prob);
                for task = 1:tasks_num
                    for algo = 1:length(data_save.algo_cell)
                        convergence_task = data_save.result(prob, algo).convergence(task:tasks_num:end, :);
                        convergence = mean(convergence_task, 1);
                        x_cell{algo} = 1:size(convergence,2);
                        y_cell{algo} = convergence;
                    end
                    switch app.YLimTypeDropDown.Value
                        case 'log(fitness)'
                            for i = 1:length(y_cell)
                                y_cell{i} = log(y_cell{i});
                            end
                    end
                    draw_obj.setXY(x_cell, y_cell);
                    draw_obj.setXYlabel('Generation', app.YLimTypeDropDown.Value);
                    draw_obj.setLegend(data_save.algo_cell);
                    draw_obj.setTitle([data_save.prob_cell{prob}, ' T', num2str(task)]);
                    draw_obj.setSaveDir(fig_dir_name);
                    draw_obj.setFigureType(app.FigureTypeDropDown.Value);
                    draw_obj.save();
                end
            end
            
            % save table
            table_dir_name = [dir_name, '/data_Excel/'];
            mkdir(table_dir_name);
            writematrix(app.FitnessUITable.Data, [table_dir_name, 'Fitness.', app.TableTypeDropDown.Value]);
            writematrix(app.FitnessStdUITable.Data, [table_dir_name, 'Fitness Std.', app.TableTypeDropDown.Value]);
            writematrix(app.TimeUITable.Data, [table_dir_name, 'Time Used.', app.TableTypeDropDown.Value]);
            
            app.printlog(['Save To: "', dir_name, '"']);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % load data_save from file
            
            [file_name, pathname] = uigetfile('data_save.mat', 'select the data_save.mat', './');
            
            % check file_name
            if ~strcmp(file_name, 'data_save.mat')
                app.printlog('!!! Please select "data_save.mat" !!! ');
                return;
            end
            
            % load data_save to app's parameter
            load([pathname, file_name], 'data_save');
            app.printlog(['Load Data: "', [pathname, file_name], '"']);
            app.data = data_save;
            app.result = app.data.result;
            app.algo_cell = app.data.algo_cell;
            app.prob_cell = app.data.prob_cell';
            app.tasks_num_list = app.data.tasks_num_list;
            app.resetTable();
            app.updateTable();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MTOPlatformUIFigure = uifigure('Visible', 'off');
            app.MTOPlatformUIFigure.Name = 'MTO Platform';
            app.GridLayout = uigridlayout(app.MTOPlatformUIFigure);
            app.Panel_1 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_1);
            app.ProblemsAddButton = uibutton(app.GridLayout3, 'push');
            app.ProblemsAddButton.ButtonPushedFcn = createCallbackFcn(app, @ProblemsAddButtonPushed, true);
            app.ProblemsAddButton.Text = 'Add';
            app.AlgorithmsAddButton = uibutton(app.GridLayout3, 'push');
            app.AlgorithmsAddButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmsAddButtonPushed, true);
            app.AlgorithmsAddButton.Text = 'Add';
            app.RepsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RepsEditField.ValueChangedFcn = createCallbackFcn(app, @RepsEditFieldValueChanged, true);
            app.RepsEditField.Value = 20;
            app.RunTimesEditFieldLabel = uilabel(app.GridLayout3);
            app.RunTimesEditFieldLabel.Text = 'Run Times';
            app.EndNumEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EndNumEditField.ValueChangedFcn = createCallbackFcn(app, @EndNumEditFieldValueChanged, true);
            app.EndNumEditField.Value = 1000;
            app.EndNumEditFieldLabel = uilabel(app.GridLayout3);
            app.EndNumEditFieldLabel.Text = 'End Num';
            app.PopSizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PopSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PopSizeEditFieldValueChanged, true);
            app.PopSizeEditField.Value = 100;
            app.PopSizeEditFieldLabel = uilabel(app.GridLayout3);
            app.PopSizeEditFieldLabel.Text = 'Pop Size';
            app.AlgorithmsListBox = uilistbox(app.GridLayout3);
            app.AlgorithmsListBox.Items = {};
            app.AlgorithmsListBox.Multiselect = 'on';
            app.AlgorithmsListBox.Value = {};
            app.AlgorithmsListBoxLabel = uilabel(app.GridLayout3);
            app.AlgorithmsListBoxLabel.Text = 'Algorithms';
            app.ProblemsListBox = uilistbox(app.GridLayout3);
            app.ProblemsListBox.Items = {};
            app.ProblemsListBox.Multiselect = 'on';
            app.ProblemsListBox.Value = {};
            app.ProblemsListBoxLabel = uilabel(app.GridLayout3);
            app.ProblemsListBoxLabel.Text = 'Problems';
            app.ButtonGroup = uibuttongroup(app.GridLayout3);
            app.ButtonGroup.Tooltip = {''};
            app.IterationButton = uiradiobutton(app.ButtonGroup);
            app.IterationButton.Text = 'Iteration';
            app.IterationButton.Value = true;
            app.EvaluationButton = uiradiobutton(app.ButtonGroup);
            app.EvaluationButton.Text = 'Evaluation';
            app.EndConditionLabel = uilabel(app.GridLayout3);
            app.EndConditionLabel.Text = 'End Condition';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.StartButton = uibutton(app.GridLayout2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.GridLayout2, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.GridLayout2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AlgorithmsTree = uitree(app.GridLayout2);
            app.AlgorithmsTree.Multiselect = 'on';
            app.AlgorithmsTree.NodeTextChangedFcn = createCallbackFcn(app, @AlgorithmsTreeNodeTextChanged, true);
            app.AlgorithmsTree.Editable = 'on';
            app.ProblemsTree = uitree(app.GridLayout2);
            app.ProblemsTree.Multiselect = 'on';
            app.ProblemsTree.NodeTextChangedFcn = createCallbackFcn(app, @ProblemsTreeNodeTextChanged, true);
            app.ProblemsTree.Editable = 'on';
            app.SelectedAlgoLabel = uilabel(app.GridLayout2);
            app.SelectedAlgoLabel.Text = 'Selected Algo';
            app.SelectedProbLabel = uilabel(app.GridLayout2);
            app.SelectedProbLabel.Text = 'Selected Prob';
            app.AlgorithmsDelButton = uibutton(app.GridLayout2, 'push');
            app.AlgorithmsDelButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmsDelButtonPushed, true);
            app.AlgorithmsDelButton.Text = 'Del';
            app.ProblemsDelButton = uibutton(app.GridLayout2, 'push');
            app.ProblemsDelButton.ButtonPushedFcn = createCallbackFcn(app, @ProblemsDelButtonPushed, true);
            app.ProblemsDelButton.Text = 'Del';
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel_3);
            app.TabGroup = uitabgroup(app.GridLayout5);
            app.FitnessTab = uitab(app.TabGroup);
            app.FitnessTab.Title = 'Fitness';
            app.GridLayout8_2 = uigridlayout(app.FitnessTab);
            app.FitnessUITable = uitable(app.GridLayout8_2);
            app.FitnessUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.FitnessUITable.RowName = {};
            app.FitnessStdTab = uitab(app.TabGroup);
            app.FitnessStdTab.Title = 'Fitness Std';
            app.GridLayout8_3 = uigridlayout(app.FitnessStdTab);
            app.FitnessStdUITable = uitable(app.GridLayout8_3);
            app.FitnessStdUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.FitnessStdUITable.RowName = {};
            app.TimeUsedTab = uitab(app.TabGroup);
            app.TimeUsedTab.Title = 'Time Used';
            app.GridLayout8 = uigridlayout(app.TimeUsedTab);
            app.TimeUITable = uitable(app.GridLayout8);
            app.TimeUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TimeUITable.RowName = {};
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.Panel_4);
            app.LogsTextAreaLabel = uilabel(app.GridLayout6);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.LogsTextArea = uitextarea(app.GridLayout6);
            app.LogsTextArea.Editable = 'off';
            app.LogsClearButton = uibutton(app.GridLayout6, 'push');
            app.LogsClearButton.ButtonPushedFcn = createCallbackFcn(app, @LogsClearButtonPushed, true);
            app.LogsClearButton.Text = 'Clear';
            app.SaveResultButton = uibutton(app.GridLayout6, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Text = 'Save Result';
            app.LoadDataButton = uibutton(app.GridLayout6, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.YLimTypeDropDownLabel = uilabel(app.GridLayout6);
            app.YLimTypeDropDownLabel.Text = 'YLim Type';
            app.YLimTypeDropDown = uidropdown(app.GridLayout6);
            app.YLimTypeDropDown.Items = {'log(fitness)', 'fitness'};
            app.YLimTypeDropDown.Value = 'log(fitness)';
            app.FigureTypeDropDownLabel = uilabel(app.GridLayout6);
            app.FigureTypeDropDownLabel.Text = 'Figure Type';
            app.FigureTypeDropDown = uidropdown(app.GridLayout6);
            app.FigureTypeDropDown.Items = {'png', 'eps', 'pdf'};
            app.FigureTypeDropDown.Value = 'png';
            app.TableTypeDropDownLabel = uilabel(app.GridLayout6);
            app.TableTypeDropDownLabel.Text = 'Table Type';
            app.TableTypeDropDown = uidropdown(app.GridLayout6);
            app.TableTypeDropDown.Items = {'csv', 'xlsx'};
            app.TableTypeDropDown.Value = 'csv';
            app.SelectedAlgoContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            app.SelectedAlgoContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @SelectedAlgoContextMenuOpening, true);
            % Assign app.SelectedAlgoContextMenu
            app.AlgorithmsTree.ContextMenu = app.SelectedAlgoContextMenu;
            app.SelectedAlgoSelectAllMenu = uimenu(app.SelectedAlgoContextMenu);
            app.SelectedAlgoSelectAllMenu.Text = 'Select All';
            app.SelectedProbContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            % Assign app.SelectedProbContextMenu
            app.ProblemsTree.ContextMenu = app.SelectedProbContextMenu;
            app.SelectedProbSelectAllMenu = uimenu(app.SelectedProbContextMenu);
            app.SelectedProbSelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectedProbSelectAllMenuSelected, true);
            app.SelectedProbSelectAllMenu.Text = 'Select All';
            app.AlgorithmsContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            app.AlgorithmsContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @AlgorithmsContextMenuOpening, true);
            % Assign app.AlgorithmsContextMenu
            app.AlgorithmsListBox.ContextMenu = app.AlgorithmsContextMenu;
            app.AlgorithmsSelectAllMenu = uimenu(app.AlgorithmsContextMenu);
            app.AlgorithmsSelectAllMenu.Text = 'Select All';
            app.ProblemsContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            % Assign app.ProblemsContextMenu
            app.ProblemsListBox.ContextMenu = app.ProblemsContextMenu;
            app.ProblemsSelectAllMenu = uimenu(app.ProblemsContextMenu);
            app.ProblemsSelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ProblemsSelectAllMenuSelected, true);
            app.ProblemsSelectAllMenu.Text = 'Select All';
            % Show the figure after all components are created
            app.MTOPlatformUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MTO_Platform

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MTOPlatformUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MTOPlatformUIFigure)
        end
    end
end